classdef TyreLife < matlab.apps.AppBase
    %TYRELIFE TyreLife ek AI-powered tyre analysis app hai jo sirf tyre ki
    %photo upload karne par uski condition predict karta hai. Ye app
    %trained deep learning model ka use karta hai jo tyre image dekh kar
    %bata sakta hai ki tyre Good, Defective, ya Average condition me hai.
    %   TyreLife App ek innovative and intelligent tyre inspection solution
    %   hai, jo users ko apne tyre ki current health condition jaanne ki
    %   suvidha deta hai bas ek image upload kar ke.
    %   
    %   Is app me:
    %   
    %   User tyre ki photo upload karta hai.
    %   
    %   App automatic trained deep learning model ka use karke photo ko
    %   analyze karta hai.
    %   
    %   Model predict karta hai tyre ki health status:
    %   
    %   Good: Tyre bilkul sahi hai.
    %   
    %   Average: Tyre beech ki condition me hai, dhyan dene ki zarurat hai.
    %   
    %   Defective: Tyre kharab hai, turant change karna chahiye.
    %   
    %   ✅ Key Features:
    %   100% AI-based Tyre Image Analysis.
    %   
    %   User-Friendly Interface.
    %   
    %   Fast Prediction Within Seconds.
    %   
    %   Tyre Image Upload + Automatic Result.
    %   
    %   No Manual Data Input Required.
    %   
    %   Secure Login (Sample Credentials).
    %   
    %   Contact Support Section Included.
    %   
    %   ✅ How It Works:
    %   User Login karta hai.
    %   
    %   Tyre Image Upload karta hai.
    %   
    %   App Image ko Deep Learning Model me Analyze karta hai.
    %   
    %   Tyre Condition Result Instant Show hota hai.
    %   
    %   ✅ Technology Stack:
    %   MATLAB App Designer.
    %   
    %   Deep Learning Image Classification Model.
    %   
    %   Automatic Model Loading.
    %   
    %   Works Offline (Once Installed).
    %   
    %   ✅ Benefits:
    %   Easy Tyre Condition Checking Without Expert Help.
    %   
    %   Saves Time & Cost.
    %   
    %   Simple & Modern UI.
    %   
    %   Potential Use for Garages, Mechanics, Vehicle Owner

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ResultLabel_2               matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        TyreConditionAnalyzerLabel  matlab.ui.control.Label
        PredictTyreConditionButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        TabGroup             matlab.ui.container.TabGroup
        LoginTab             matlab.ui.container.Tab
        EmailEditField       matlab.ui.control.EditField
        PasswordEditField    matlab.ui.control.EditField
        LoginButton          matlab.ui.control.Button
        HomeTab              matlab.ui.container.Tab
        UploadButton         matlab.ui.control.Button
        ContactButton        matlab.ui.control.Button
        UploadTab            matlab.ui.container.Tab
        BrowseButton         matlab.ui.control.Button
        TyreImage            matlab.ui.control.Image
        PredictButton        matlab.ui.control.Button
        ResultTab            matlab.ui.container.Tab
        ResultLabel          matlab.ui.control.Label
        ContactTab           matlab.ui.container.Tab
        EmailLabel           matlab.ui.control.Label
        PhoneLabel           matlab.ui.control.Label 
    end
    
    properties (Access = private)
        trainedNet           % Loaded Deep Learning Model
        ImagePath            % Uploaded Image Path
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             try
        modelData = load('tyreClassifier.mat', 'trainedNet');
        app.trainedNet = modelData.trainedNet;
        disp('Model loaded successfully.');
    catch
        uialert(app.UIFigure, 'Model file not found. Please ensure tyreClassifier.mat is in the same folder.', 'Model Load Error');
             end
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.jpeg'}, 'Select Tyre Image');
    if isequal(file, 0)
        disp('User canceled.');
    else
        app.ImagePath = fullfile(path, file);
        app.Image.ImageSource = app.ImagePath;  % Corrected line
    end
        end

        % Button pushed function: PredictTyreConditionButton
        function PredictTyreConditionButtonPushed(app, event)
               try
        if isempty(app.ImagePath) || ~isfile(app.ImagePath)
            uialert(app.UIFigure, 'Please upload a valid image first.', 'Prediction Error');
            return;
        end

        % Read and preprocess image
        img = imread(app.ImagePath);
        img = imresize(img, [224 224]);
        if size(img, 3) == 1
            img = cat(3, img, img, img);
        end

        % Predict using trained model
        label = classify(app.trainedNet, img);

        % Debugging Output (Console Me Result)
        disp("Prediction Output:");
        disp(label);
        disp(class(label));

        % Prepare Result Text (Clean & Meaningful)
        resultText = sprintf('Predicted Tyre Condition: %s', char(label));

        % Show Result in App Label (UI)
        app.ResultLabel_2.Text = resultText;

    catch err
        uialert(app.UIFigure, sprintf('Error: %s', err.message), 'Prediction Error');
               end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PredictTyreConditionButton = uibutton(app.UIFigure, 'push');
            app.PredictTyreConditionButton.ButtonPushedFcn = createCallbackFcn(app, @PredictTyreConditionButtonPushed, true);
            app.PredictTyreConditionButton.Text = 'Predict Tyre Condition';
            app.TyreConditionAnalyzerLabel = uilabel(app.UIFigure);
            app.TyreConditionAnalyzerLabel.Text = 'Tyre Condition Analyzer';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.ResultLabel_2 = uilabel(app.UIFigure);
            app.ResultLabel_2.Text = 'Result';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TyreLife

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end